classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Button                  matlab.ui.control.Button
        Label                   matlab.ui.control.Label
        E1EditFieldLabel        matlab.ui.control.Label
        E1EditField             matlab.ui.control.NumericEditField
        UITable                 matlab.ui.control.Table
        E2EditFieldLabel        matlab.ui.control.Label
        E2EditField             matlab.ui.control.NumericEditField
        v12EditFieldLabel       matlab.ui.control.Label
        v12EditField            matlab.ui.control.NumericEditField
        G12EditFieldLabel       matlab.ui.control.Label
        G12EditField            matlab.ui.control.NumericEditField
        fxEditFieldLabel        matlab.ui.control.Label
        fxEditField             matlab.ui.control.NumericEditField
        fyEditFieldLabel        matlab.ui.control.Label
        fyEditField             matlab.ui.control.NumericEditField
        fxyEditFieldLabel       matlab.ui.control.Label
        fxyEditField            matlab.ui.control.NumericEditField
        NyEditFieldLabel        matlab.ui.control.Label
        NyEditField             matlab.ui.control.NumericEditField
        NxyEditFieldLabel       matlab.ui.control.Label
        NxyEditField            matlab.ui.control.NumericEditField
        MxEditFieldLabel        matlab.ui.control.Label
        MxEditField             matlab.ui.control.NumericEditField
        MyEditFieldLabel        matlab.ui.control.Label
        MyEditField             matlab.ui.control.NumericEditField
        MxyEditFieldLabel       matlab.ui.control.Label
        MxyEditField            matlab.ui.control.NumericEditField
        NumPliesEditFieldLabel  matlab.ui.control.Label
        NumPliesEditField       matlab.ui.control.NumericEditField
        NxEditFieldLabel        matlab.ui.control.Label
        NxEditField             matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
%           Set Mat Const values as inputs
            E1 = app.E1EditField.Value;
            E2 = app.E2EditField.Value;
            v12 = app.v12EditField.Value;
            G12 = app.G12EditField.Value;
            NumPlies = app.NumPliesEditField.Value;
            fx = app.fxEditField.Value;
            fy = app.fyEditField.Value;
            fxy = app.fxyEditField.Value;
            Nx = app.NxEditField.Value;
            Ny = app.NyEditField.Value;
            Nxy = app.NxyEditField.Value;
            Mx = app.MxEditField.Value;
            My = app.MyEditField.Value;
            Mxy = app.MxyEditField.Value;
            

            app.Label.Text = num2str(E1);
            
%           Mat Const values structure
            MatConst.E1 = E1;
            MatConst.E2 = E2;
            MatConst.v12 = v12;
            MatConst.v21 = (MatConst.E2*MatConst.v12)/MatConst.E1;
            MatConst.G12 = G12;
            MatConst.NumPlies = NumPlies;
            MatConst.fx = fx;
            MatConst.fy = fy;
            MatConst.fxy = fxy;
            MatConst.Nx = Nx;
            MatConst.Ny = Ny;
            MatConst.Nxy = Nxy;
            MatConst.Mx = Mx;
            MatConst.My = My;
            MatConst.Mxy = Mxy;
            
            output = GUIReferencingMain(MatConst);
            app.UITable.Data = (output);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Label = uilabel(app.UIFigure);
            app.E1EditFieldLabel = uilabel(app.UIFigure);
            app.E1EditFieldLabel.Text = 'E1';
            app.E1EditField = uieditfield(app.UIFigure, 'numeric');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.E2EditFieldLabel = uilabel(app.UIFigure);
            app.E2EditFieldLabel.Text = 'E2';
            app.E2EditField = uieditfield(app.UIFigure, 'numeric');
            app.v12EditFieldLabel = uilabel(app.UIFigure);
            app.v12EditFieldLabel.Text = 'v12';
            app.v12EditField = uieditfield(app.UIFigure, 'numeric');
            app.G12EditFieldLabel = uilabel(app.UIFigure);
            app.G12EditFieldLabel.Text = 'G12';
            app.G12EditField = uieditfield(app.UIFigure, 'numeric');
            app.fxEditFieldLabel = uilabel(app.UIFigure);
            app.fxEditFieldLabel.Text = 'fx';
            app.fxEditField = uieditfield(app.UIFigure, 'numeric');
            app.fyEditFieldLabel = uilabel(app.UIFigure);
            app.fyEditFieldLabel.Text = 'fy';
            app.fyEditField = uieditfield(app.UIFigure, 'numeric');
            app.fxyEditFieldLabel = uilabel(app.UIFigure);
            app.fxyEditFieldLabel.Text = 'fxy';
            app.fxyEditField = uieditfield(app.UIFigure, 'numeric');
            app.NyEditFieldLabel = uilabel(app.UIFigure);
            app.NyEditFieldLabel.Text = 'Ny';
            app.NyEditField = uieditfield(app.UIFigure, 'numeric');
            app.NxyEditFieldLabel = uilabel(app.UIFigure);
            app.NxyEditFieldLabel.Text = 'Nxy';
            app.NxyEditField = uieditfield(app.UIFigure, 'numeric');
            app.MxEditFieldLabel = uilabel(app.UIFigure);
            app.MxEditFieldLabel.Text = 'Mx';
            app.MxEditField = uieditfield(app.UIFigure, 'numeric');
            app.MyEditFieldLabel = uilabel(app.UIFigure);
            app.MyEditFieldLabel.Text = 'My';
            app.MyEditField = uieditfield(app.UIFigure, 'numeric');
            app.MxyEditFieldLabel = uilabel(app.UIFigure);
            app.MxyEditFieldLabel.Text = 'Mxy';
            app.MxyEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumPliesEditFieldLabel = uilabel(app.UIFigure);
            app.NumPliesEditFieldLabel.Text = 'NumPlies';
            app.NumPliesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NxEditFieldLabel = uilabel(app.UIFigure);
            app.NxEditFieldLabel.Text = 'Nx';
            app.NxEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end